classdef Project_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SemiAutomatedBloodCellAnalysesUIFigure  matlab.ui.Figure
        Title                      matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        ProgressLabel              matlab.ui.control.Label
        wbc                        matlab.ui.control.Label
        WBC                        matlab.ui.control.Label
        RBC                        matlab.ui.control.Label
        rbc                        matlab.ui.control.Label
        PLT                        matlab.ui.control.Label
        plt                        matlab.ui.control.Label
        footer                     matlab.ui.control.Label
        progress                   matlab.ui.control.Label
        Button                     matlab.ui.control.Button
        PatientNameEditFieldLabel  matlab.ui.control.Label
        name                       matlab.ui.control.EditField
        PatientIDLabel             matlab.ui.control.Label
        id                         matlab.ui.control.NumericEditField
    end


    methods (Access = public)
    
        function progress_text(app, t)
            app.progress.Text = t;
            drawnow
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.id.Value > 0 && strlength(app.name.Value)
            
                app.progress.Text = 'Running';
                drawnow
                r = get_image;
                app.wbc.Text = num2str(r(2)* 120/1000);
                app.rbc.Text = num2str(r(1)* 120/1000000);
                app.plt.Text = num2str(r(3)* 120/1000);
                app.progress.Text = 'Done';
                beep
            else
                app.progress.Text = 'Please Enter Patient ID and Name';
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SemiAutomatedBloodCellAnalysesUIFigure = uifigure;
            app.SemiAutomatedBloodCellAnalysesUIFigure.Name = 'Semi-Automated Blood Cell Analyses';
            app.Title = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.Title.Text = {'Semi-Automated Bloood Cell Analyses '; 'by Using Digital Image Processing'};
            app.StartButton = uibutton(app.SemiAutomatedBloodCellAnalysesUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ProgressLabel = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.ProgressLabel.Text = 'Progress:';
            app.wbc = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.wbc.Text = '0';
            app.WBC = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.WBC.Text = 'WBC (10³):';
            app.RBC = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.RBC.Text = 'RBC (10⁶):';
            app.rbc = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.rbc.Text = '0';
            app.PLT = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.PLT.Text = 'PLT (10³):';
            app.plt = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.plt.Text = '0';
            app.footer = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.footer.Text = 'by Saeed Bamashmos';
            app.progress = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.progress.Text = 'Ready';
            app.Button = uibutton(app.SemiAutomatedBloodCellAnalysesUIFigure, 'push');
            app.Button.Icon = 'liu.png';
            app.Button.Text = '';
            app.PatientNameEditFieldLabel = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.PatientNameEditFieldLabel.Text = 'Patient Name';
            app.name = uieditfield(app.SemiAutomatedBloodCellAnalysesUIFigure, 'text');
            app.PatientIDLabel = uilabel(app.SemiAutomatedBloodCellAnalysesUIFigure);
            app.PatientIDLabel.Text = 'Patient ID';
            app.id = uieditfield(app.SemiAutomatedBloodCellAnalysesUIFigure, 'numeric');
            app.id.Limits = [0 999999];
            app.id.ValueDisplayFormat = '%.0f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Project_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SemiAutomatedBloodCellAnalysesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SemiAutomatedBloodCellAnalysesUIFigure)
        end
    end
end